classdef scanUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RunButton                    matlab.ui.control.Button
        Note4                        matlab.ui.control.Label
        Note3                        matlab.ui.control.Label
        Note2                        matlab.ui.control.Label
        Note1                        matlab.ui.control.Label
        time_per_locLabel            matlab.ui.control.Label
        time_at_loc_EditField        matlab.ui.control.NumericEditField
        AtlocationsecondsEditFieldLabel  matlab.ui.control.Label
        time_till_on_EditField       matlab.ui.control.NumericEditField
        TilllaseronsecondsEditFieldLabel  matlab.ui.control.Label
        RunParametersLabel           matlab.ui.control.Label
        UnitsAccel                   matlab.ui.control.DropDown
        UnitsDropDown_2Label         matlab.ui.control.Label
        xAccel                       matlab.ui.control.NumericEditField
        xAccelerationEditFieldLabel  matlab.ui.control.Label
        yAccel                       matlab.ui.control.NumericEditField
        yAccelerationEditFieldLabel  matlab.ui.control.Label
        UnitsDist                    matlab.ui.control.DropDown
        UnitsDropDownLabel           matlab.ui.control.Label
        xdelta                       matlab.ui.control.NumericEditField
        xdeltaEditFieldLabel         matlab.ui.control.Label
        ydelta                       matlab.ui.control.NumericEditField
        ydeltaEditField_2Label       matlab.ui.control.Label
        n_rows                       matlab.ui.control.NumericEditField
        NumberofRowsEditFieldLabel   matlab.ui.control.Label
        n_cols                       matlab.ui.control.NumericEditField
        NumberofColumnsEditField_3Label  matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
            
                %connect
                connection = p1_controls.connect();
                if connection == false
                    %unable to connect
                    app.RunButton.BackgroundColor = 'red';
                    return
                end
                fprintf("connected\n")
                
                %check number of devices
                val = p1_controls.validated_connection(connection);
                if val == false
                    %can't do anything
                    %connection is closed
                    app.RunButton.BackgroundColor = 'orange';
                    return
                end
                app.RunButton.BackgroundColor = [0.40,0.90,0.40];
                
                %get axes
                [x_axis, y_axis] = p1_controls.getAxes(connection);
                
                %set acceleration
                x_accel = app.xAccel.Value;
                y_accel = app.yAccel.Value;
                units_accel = app.UnitsAccel.Value;
                [initial_accel_x, initial_accel_y] = ...
                    p1_controls.setAccel(x_axis, y_axis, x_accel, y_accel, units_accel);
                
                %scan plan
                %movement
                output_file = p1_controls.run_scan(x_axis, y_axis, ...
                    app.n_rows.Value, app.xdelta.Value, ...
                    app.n_cols.Value, app.ydelta.Value, app.UnitsDist.Value,...
                    app.time_till_on_EditField.Value , app.time_at_loc_EditField.Value);
                fprintf(output_file)
                
                %reset acceleration
                p1_controls.setAccel(x_axis, y_axis, initial_accel_x, initial_accel_y, units_accel);

                connection.close()
            catch exception
                % This happens if there was any problem in the above section
                %   to make sure the connection closes anyway.
                fprintf("Fail\n")
                connection.close();
                rethrow(exception);
            end
            fprintf("Done.")
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {531, 531};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {204, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.NumberofColumnsEditField_3Label = uilabel(app.LeftPanel);
            app.NumberofColumnsEditField_3Label.Text = 'Number of Columns';
            app.n_cols = uieditfield(app.LeftPanel, 'numeric');
            app.n_cols.Limits = [0 Inf];
            app.n_cols.Value = 6;
            app.NumberofRowsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofRowsEditFieldLabel.Text = 'Number of Rows';
            app.n_rows = uieditfield(app.LeftPanel, 'numeric');
            app.n_rows.Limits = [0 Inf];
            app.n_rows.Value = 3;
            app.ydeltaEditField_2Label = uilabel(app.LeftPanel);
            app.ydeltaEditField_2Label.Text = 'y delta';
            app.ydelta = uieditfield(app.LeftPanel, 'numeric');
            app.ydelta.Limits = [0 Inf];
            app.ydelta.Value = 0.5;
            app.xdeltaEditFieldLabel = uilabel(app.LeftPanel);
            app.xdeltaEditFieldLabel.Text = 'x delta';
            app.xdelta = uieditfield(app.LeftPanel, 'numeric');
            app.xdelta.Limits = [0 Inf];
            app.xdelta.Value = 0.5;
            app.UnitsDropDownLabel = uilabel(app.LeftPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDist = uidropdown(app.LeftPanel);
            app.UnitsDist.Items = {'METRES', 'CENTIMETRES', 'MILLIMETRES', 'MICROMETRES', 'NANOMETRES'};
            app.UnitsDist.Value = 'MILLIMETRES';
            app.yAccelerationEditFieldLabel = uilabel(app.LeftPanel);
            app.yAccelerationEditFieldLabel.Text = 'y Acceleration';
            app.yAccel = uieditfield(app.LeftPanel, 'numeric');
            app.yAccel.Limits = [0.001 59.5894];
            app.yAccel.Value = 15;
            app.xAccelerationEditFieldLabel = uilabel(app.LeftPanel);
            app.xAccelerationEditFieldLabel.Text = 'x Acceleration';
            app.xAccel = uieditfield(app.LeftPanel, 'numeric');
            app.xAccel.Limits = [0.001 59.5894];
            app.xAccel.Value = 15;
            app.UnitsDropDown_2Label = uilabel(app.LeftPanel);
            app.UnitsDropDown_2Label.Text = 'Units';
            app.UnitsAccel = uidropdown(app.LeftPanel);
            app.UnitsAccel.Items = {'CENTIMETRES', 'MILLIMETRES', 'MICROMETRES', 'NANOMETRES'};
            app.UnitsAccel.Value = 'MILLIMETRES';
            app.RunParametersLabel = uilabel(app.LeftPanel);
            app.RunParametersLabel.Text = 'Run Parameters';
            app.TilllaseronsecondsEditFieldLabel = uilabel(app.LeftPanel);
            app.TilllaseronsecondsEditFieldLabel.Text = 'Till laser on (seconds)';
            app.time_till_on_EditField = uieditfield(app.LeftPanel, 'numeric');
            app.time_till_on_EditField.Limits = [0 Inf];
            app.time_till_on_EditField.Tooltip = {'Note that time till laser on refers to when on instruction is sent. There will be a ~5 second delay till the laser hits the sample.'};
            app.time_till_on_EditField.Value = 0.1;
            app.AtlocationsecondsEditFieldLabel = uilabel(app.LeftPanel);
            app.AtlocationsecondsEditFieldLabel.Text = 'At location (seconds)';
            app.time_at_loc_EditField = uieditfield(app.LeftPanel, 'numeric');
            app.time_at_loc_EditField.Limits = [0 Inf];
            app.time_at_loc_EditField.Value = 0.2;
            app.time_per_locLabel = uilabel(app.LeftPanel);
            app.time_per_locLabel.Text = 'Time per location';
            app.Note1 = uilabel(app.LeftPanel);
            app.Note1.Tooltip = {'Note that time till laser on refers to '};
            app.Note1.Text = 'Note that time till laser on refers to ';
            app.Note2 = uilabel(app.LeftPanel);
            app.Note2.Tooltip = {''};
            app.Note2.Text = 'when on instruction is sent.';
            app.Note3 = uilabel(app.LeftPanel);
            app.Note3.Text = 'There will be a 5 second delay till ';
            app.Note4 = uilabel(app.LeftPanel);
            app.Note4.Text = 'the laser hits the sample.';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scanUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end